classdef RobotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PizzaMenuPanel             matlab.ui.container.Panel
        PrepareLabel               matlab.ui.control.Label
        Image                      matlab.ui.control.Image
        PizzaSelectionButtonGroup  matlab.ui.container.ButtonGroup
        FourCheesesButton          matlab.ui.control.RadioButton
        PepperoniButton            matlab.ui.control.RadioButton
        HawaiianButton             matlab.ui.control.RadioButton
        MargheritaButton           matlab.ui.control.RadioButton
        RobotControlPanel          matlab.ui.container.Panel
        RobotDropDown              matlab.ui.control.DropDown
        RobotDropDownLabel         matlab.ui.control.Label
        CartesianControlPanel      matlab.ui.container.Panel
        ZButton_2                  matlab.ui.control.Button
        YButton_2                  matlab.ui.control.Button
        XButton_2                  matlab.ui.control.Button
        ZButton                    matlab.ui.control.Button
        YButton                    matlab.ui.control.Button
        XButton                    matlab.ui.control.Button
        RobotJointControlPanel     matlab.ui.container.Panel
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Q6Slider                   matlab.ui.control.Slider
        Q6SliderLabel              matlab.ui.control.Label
        Q5Slider                   matlab.ui.control.Slider
        Q5SliderLabel              matlab.ui.control.Label
        Q4Slider                   matlab.ui.control.Slider
        Q4SliderLabel              matlab.ui.control.Label
        Q3Slider                   matlab.ui.control.Slider
        Q3SliderLabel              matlab.ui.control.Label
        Q2Slider                   matlab.ui.control.Slider
        Q2SliderLabel              matlab.ui.control.Label
        Q1Slider                   matlab.ui.control.Slider
        Q1SliderLabel              matlab.ui.control.Label
        Button                     matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image
        function PreparePizza(app, event)
            
        end

        % Value changing function: Q6Slider
        function Q6SliderValueChanging(app, event)
            changingValue = event.Value;
            Movement.JointJogRobot(robot,6,changingValue);
        end

        % Value changing function: Q5Slider
        function Q5SliderValueChanging(app, event)
            changingValue = event.Value;
            Movement.JointJogRobot(robot,5,changingValue);
        end

        % Value changing function: Q4Slider
        function Q4SliderValueChanging(app, event)
            changingValue = event.Value;
            Movement.JointJogRobot(robot,4,changingValue);
        end

        % Value changing function: Q3Slider
        function Q3SliderValueChanging(app, event)
            changingValue = event.Value;
            Movement.JointJogRobot(robot,3,changingValue);
        end

        % Value changing function: Q2Slider
        function Q2SliderValueChanging(app, event)
            changingValue = event.Value;
            Movement.JointJogRobot(robot,2,changingValue);
        end

        % Value changing function: Q1Slider
        function Q1SliderValueChanging(app, event)
            changingValue = event.Value;
            Movement.JointJogRobot(robot,1,changingValue);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            Movement.CartJogRobot(robot,'+x');
        end

        % Button pushed function: XButton_2
        function XButton_2Pushed(app, event)
            Movement.CartJogRobot(robot,'-x');
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            Movement.CartJogRobot(robot,'+y');
        end

        % Button pushed function: YButton_2
        function YButton_2Pushed(app, event)
            Movement.CartJogRobot(robot,'-y');
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            Movement.CartJogRobot(robot,'+z');
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            Movement.CartJogRobot(robot,'-z');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Icon = fullfile(pathToMLAPP, 'GUI', 'Images', 'Asset 31.jpeg');
            app.Button.Text = '';
            app.RobotControlPanel = uipanel(app.UIFigure);
            app.RobotControlPanel.Title = 'Robot Control Panel';
            app.RobotJointControlPanel = uipanel(app.RobotControlPanel);
            app.RobotJointControlPanel.Title = 'Robot Joint Control';
            app.Q1SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q1SliderLabel.Text = 'Q1';
            app.Q1Slider = uislider(app.RobotJointControlPanel);
            app.Q1Slider.MajorTicks = [];
            app.Q1Slider.MajorTickLabels = {''};
            app.Q1Slider.ValueChangingFcn = createCallbackFcn(app, @Q1SliderValueChanging, true);
            app.Q1Slider.MinorTicks = [];
            app.Q1Slider.Value = 50;
            app.Q2SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q2SliderLabel.Text = 'Q2';
            app.Q2Slider = uislider(app.RobotJointControlPanel);
            app.Q2Slider.MajorTicks = [];
            app.Q2Slider.MajorTickLabels = {''};
            app.Q2Slider.ValueChangingFcn = createCallbackFcn(app, @Q2SliderValueChanging, true);
            app.Q2Slider.MinorTicks = [];
            app.Q2Slider.Value = 50;
            app.Q3SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q3SliderLabel.Text = 'Q3';
            app.Q3Slider = uislider(app.RobotJointControlPanel);
            app.Q3Slider.MajorTicks = [];
            app.Q3Slider.MajorTickLabels = {''};
            app.Q3Slider.ValueChangingFcn = createCallbackFcn(app, @Q3SliderValueChanging, true);
            app.Q3Slider.MinorTicks = [];
            app.Q3Slider.Value = 50;
            app.Q4SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q4SliderLabel.Text = 'Q4';
            app.Q4Slider = uislider(app.RobotJointControlPanel);
            app.Q4Slider.MajorTicks = [];
            app.Q4Slider.MajorTickLabels = {''};
            app.Q4Slider.ValueChangingFcn = createCallbackFcn(app, @Q4SliderValueChanging, true);
            app.Q4Slider.MinorTicks = [];
            app.Q4Slider.Value = 50;
            app.Q5SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q5SliderLabel.Text = 'Q5';
            app.Q5Slider = uislider(app.RobotJointControlPanel);
            app.Q5Slider.MajorTicks = [];
            app.Q5Slider.MajorTickLabels = {''};
            app.Q5Slider.ValueChangingFcn = createCallbackFcn(app, @Q5SliderValueChanging, true);
            app.Q5Slider.MinorTicks = [];
            app.Q5Slider.Value = 50;
            app.Q6SliderLabel = uilabel(app.RobotJointControlPanel);
            app.Q6SliderLabel.Text = 'Q6';
            app.Q6Slider = uislider(app.RobotJointControlPanel);
            app.Q6Slider.MajorTicks = [];
            app.Q6Slider.MajorTickLabels = {''};
            app.Q6Slider.ValueChangingFcn = createCallbackFcn(app, @Q6SliderValueChanging, true);
            app.Q6Slider.MinorTicks = [];
            app.Q6Slider.Value = 50;
            app.Label = uilabel(app.RobotJointControlPanel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.RobotJointControlPanel);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.RobotJointControlPanel);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.RobotJointControlPanel);
            app.Label_4.Text = '0';
            app.Label_5 = uilabel(app.RobotJointControlPanel);
            app.Label_5.Text = '0';
            app.Label_6 = uilabel(app.RobotJointControlPanel);
            app.Label_6.Text = '0';
            app.CartesianControlPanel = uipanel(app.RobotControlPanel);
            app.CartesianControlPanel.Title = 'Cartesian Control';
            app.XButton = uibutton(app.CartesianControlPanel, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = '+X';
            app.YButton = uibutton(app.CartesianControlPanel, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = '+Y';
            app.ZButton = uibutton(app.CartesianControlPanel, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = '+Z';
            app.XButton_2 = uibutton(app.CartesianControlPanel, 'push');
            app.XButton_2.ButtonPushedFcn = createCallbackFcn(app, @XButton_2Pushed, true);
            app.XButton_2.Text = '-X';
            app.YButton_2 = uibutton(app.CartesianControlPanel, 'push');
            app.YButton_2.ButtonPushedFcn = createCallbackFcn(app, @YButton_2Pushed, true);
            app.YButton_2.Text = '-Y';
            app.ZButton_2 = uibutton(app.CartesianControlPanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '-Z';
            app.RobotDropDownLabel = uilabel(app.RobotControlPanel);
            app.RobotDropDownLabel.Text = 'Robot:';
            app.RobotDropDown = uidropdown(app.RobotControlPanel);
            app.RobotDropDown.Items = {'IRB-1200 (Guido)', 'UR3e (Luigi)'};
            app.RobotDropDown.Value = 'IRB-1200 (Guido)';
            app.PizzaMenuPanel = uipanel(app.UIFigure);
            app.PizzaMenuPanel.Title = 'Pizza Menu';
            app.PizzaSelectionButtonGroup = uibuttongroup(app.PizzaMenuPanel);
            app.PizzaSelectionButtonGroup.Title = 'Pizza Selection';
            app.MargheritaButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.MargheritaButton.Text = 'Margherita';
            app.MargheritaButton.Value = true;
            app.HawaiianButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.HawaiianButton.Text = 'Hawaiian';
            app.PepperoniButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.PepperoniButton.Text = 'Pepperoni';
            app.FourCheesesButton = uiradiobutton(app.PizzaSelectionButtonGroup);
            app.FourCheesesButton.Text = 'Four Cheeses';
            app.Image = uiimage(app.PizzaMenuPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @PreparePizza, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'GUI', 'Images', 'elmo fire.jpg');
            app.PrepareLabel = uilabel(app.PizzaMenuPanel);
            app.PrepareLabel.Text = ' Prepare🤌';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RobotGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end